classdef EMMALAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EMMALABv10Label_2              matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        IntroPageTab                   matlab.ui.container.Tab
        Panel_6                        matlab.ui.container.Panel
        Panel_7                        matlab.ui.container.Panel
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        TextArea_3                     matlab.ui.control.TextArea
        EMMALABv10Label                matlab.ui.control.Label
        Image_6                        matlab.ui.control.Image
        Image_5                        matlab.ui.control.Image
        Image_4                        matlab.ui.control.Image
        Image_3                        matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        DataTab                        matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        DataPickerPanel                matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        LoadDatasetButton              matlab.ui.control.Button
        UITable15                      matlab.ui.control.Table
        SetPreferencesButton           matlab.ui.control.Button
        UITable13                      matlab.ui.control.Table
        EndDateDropDown                matlab.ui.control.DropDown
        EndDateDropDownLabel           matlab.ui.control.Label
        StartDateDropDown              matlab.ui.control.DropDown
        StartDateDropDownLabel         matlab.ui.control.Label
        PlotOptionsTab                 matlab.ui.container.Tab
        EndmemberDataLabel             matlab.ui.control.Label
        MixtureDataLabel               matlab.ui.control.Label
        UITable5_2                     matlab.ui.control.Table
        SavePreferencesButton          matlab.ui.control.Button
        LoadPreferencesButton          matlab.ui.control.Button
        CategoryDetailsPanel           matlab.ui.container.Panel
        DataTypeButtonGroup            matlab.ui.container.ButtonGroup
        EndmemberButton                matlab.ui.control.RadioButton
        MainButton                     matlab.ui.control.RadioButton
        CreateCategoryButton           matlab.ui.control.Button
        CategoryNameEditField          matlab.ui.control.EditField
        CategoryNameEditFieldLabel     matlab.ui.control.Label
        ColorSelectDropDown            matlab.ui.control.DropDown
        ColorSelectDropDownLabel       matlab.ui.control.Label
        SymbolSelectDropDown           matlab.ui.control.DropDown
        SymbolSelectDropDownLabel      matlab.ui.control.Label
        LocationPanel                  matlab.ui.container.Panel
        LocationListBox                matlab.ui.control.ListBox
        LocationListBoxLabel           matlab.ui.control.Label
        UITable5                       matlab.ui.control.Table
        CorrelationMatrixTab           matlab.ui.container.Tab
        ElementSelectorPanel           matlab.ui.container.Panel
        PlotStatusLamp                 matlab.ui.control.Lamp
        PlotStatusLampLabel            matlab.ui.control.Label
        CreateCorrelationMatrixButton  matlab.ui.control.Button
        UITable11                      matlab.ui.control.Table
        TabGroup3                      matlab.ui.container.TabGroup
        ScatterPlotCorrelationsTab     matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
        CorrelationsbyRTab             matlab.ui.container.Tab
        Panel_2                        matlab.ui.container.Panel
        PCATab                         matlab.ui.container.Tab
        EndmemberButtonGroup           matlab.ui.container.ButtonGroup
        AveragedEndmembersButton       matlab.ui.control.RadioButton
        EndmembersallButton            matlab.ui.control.RadioButton
        SavePCDataButton               matlab.ui.control.Button
        PCAPreferencesPanel            matlab.ui.container.Panel
        ClearPlotButton_4              matlab.ui.control.Button
        ZAxisDropDown_2                matlab.ui.control.DropDown
        ZaxisDropDownLabel             matlab.ui.control.Label
        YAxisDropDown                  matlab.ui.control.DropDown
        YaxesLabel                     matlab.ui.control.Label
        XAxisDropDown                  matlab.ui.control.DropDown
        XaxisLabel                     matlab.ui.control.Label
        PercentExplainedPanel          matlab.ui.container.Panel
        UITable10                      matlab.ui.control.Table
        TracerSelectionPanel           matlab.ui.container.Panel
        UITable9                       matlab.ui.control.Table
        TabGroup5                      matlab.ui.container.TabGroup
        DPCATab                        matlab.ui.container.Tab
        UIAxes7                        matlab.ui.control.UIAxes
        DPCATab_2                      matlab.ui.container.Tab
        UIAxes8                        matlab.ui.control.UIAxes
        ResidualsTab                   matlab.ui.container.Tab
        CalculateResidualsPanel        matlab.ui.container.Panel
        ResidualsDropDown              matlab.ui.control.DropDown
        PlotResidualsforDropDownLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        ofPCsSpinner                   matlab.ui.control.Spinner
        ofPCsSpinnerLabel              matlab.ui.control.Label
        PlottingOptionsButtonGroup     matlab.ui.container.ButtonGroup
        ScreePlotButton                matlab.ui.control.RadioButton
        ResidualsButton                matlab.ui.control.RadioButton
        UIAxes2                        matlab.ui.control.UIAxes
        CoefficientsTab                matlab.ui.container.Tab
        UITable3                       matlab.ui.control.Table
        PCScoresTab                    matlab.ui.container.Tab
        UITable4                       matlab.ui.control.Table
        MixingCalculationTab           matlab.ui.container.Tab
        TabGroup7                      matlab.ui.container.TabGroup
        MixingSummaryTab               matlab.ui.container.Tab
        MixingAnalysisSummaryPanel     matlab.ui.container.Panel
        USpaceCalculationPreferencesButtonGroup  matlab.ui.container.ButtonGroup
        PCButton_2                     matlab.ui.control.RadioButton
        PCButton                       matlab.ui.control.RadioButton
        MixingCalculationPreferencesButtonGroup  matlab.ui.container.ButtonGroup
        USpacenotrecommendedButton     matlab.ui.control.RadioButton
        TracerSpaceButton              matlab.ui.control.RadioButton
        SaveMixingResultsButton        matlab.ui.control.Button
        CalculationStatusLamp          matlab.ui.control.Lamp
        CalculationStatusLampLabel     matlab.ui.control.Label
        CalculateFractionalContributionsButton  matlab.ui.control.Button
        UITable12                      matlab.ui.control.Table
        SelEMListBox                   matlab.ui.control.ListBox
        SelectedEndmembersLabel        matlab.ui.control.Label
        LocListBox                     matlab.ui.control.ListBox
        LocationsinanalysisLabel       matlab.ui.control.Label
        UITable6                       matlab.ui.control.Table
        MixingPlotViewerTab            matlab.ui.container.Tab
        Panel_3                        matlab.ui.container.Panel
        PlotFractionalContributionOverTimeButton  matlab.ui.control.Button
        LocationDropDown               matlab.ui.control.DropDown
        LocationDropDownLabel          matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        PredictedvObservedTab          matlab.ui.container.Tab
        PredictedCalculationSettingsPanel  matlab.ui.container.Panel
        UITable16                      matlab.ui.control.Table
        ClearPlotButton_5              matlab.ui.control.Button
        PlotPredictionButton           matlab.ui.control.Button
        TracerDropDown                 matlab.ui.control.DropDown
        TracerDropDownLabel            matlab.ui.control.Label
        UIAxes9                        matlab.ui.control.UIAxes
        ErrorCalculationTab            matlab.ui.container.Tab
        SaveJackknifeDataButton        matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        ErrorCalculationPanel          matlab.ui.container.Panel
        TimeElapsedminTextArea         matlab.ui.control.TextArea
        TimeElapsedminTextAreaLabel    matlab.ui.control.Label
        ErrorCalculationStatusLamp     matlab.ui.control.Lamp
        ErrorCalculationStatusLampLabel  matlab.ui.control.Label
        CalculatePercentErrorButton    matlab.ui.control.Button
        ErrorPlotterPanel              matlab.ui.container.Panel
        ClearPlotButton_6              matlab.ui.control.Button
        PlotErrorButton                matlab.ui.control.Button
        LocationDropDown_2             matlab.ui.control.DropDown
        LocationDropDown_2Label        matlab.ui.control.Label
        TabGroup8                      matlab.ui.container.TabGroup
        ErrorPlotTab                   matlab.ui.container.Tab
        UIAxes6                        matlab.ui.control.UIAxes
        JackknifedMeanValuesTab        matlab.ui.container.Tab
        JackknifedCalculatedFractionalContributionsLabel  matlab.ui.control.Label
        UITable7                       matlab.ui.control.Table
        JackknifedStandardDeviationValuesTab  matlab.ui.container.Tab
        JackknifedCalculatedStandardDeviationLabel  matlab.ui.control.Label
        UITable8                       matlab.ui.control.Table
        PlotViewerTab                  matlab.ui.container.Tab
        TabGroup6                      matlab.ui.container.TabGroup
        TimeSeriesTab                  matlab.ui.container.Tab
        AxesTitlePreferencesPanel      matlab.ui.container.Panel
        TitleEditField                 matlab.ui.control.EditField
        TitleEditFieldLabel            matlab.ui.control.Label
        YAxisLabelEditField            matlab.ui.control.EditField
        YAxisLabelEditFieldLabel       matlab.ui.control.Label
        XAxisLabelEditField            matlab.ui.control.EditField
        XAxisLabelEditFieldLabel       matlab.ui.control.Label
        UpdateAxesLabelsButton         matlab.ui.control.Button
        TimeSeriesPreferencesPanel     matlab.ui.container.Panel
        ClearPlotButton_2              matlab.ui.control.Button
        YAxisDropDown_2                matlab.ui.control.DropDown
        YAxisDropDown_7Label           matlab.ui.control.Label
        XAxisDropDown_2                matlab.ui.control.DropDown
        XAxisDropDown_7Label           matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        HelpTab                        matlab.ui.container.Tab
        Panel_4                        matlab.ui.container.Panel
        Panel_5                        matlab.ui.container.Panel
        Image_7                        matlab.ui.control.Image
        CitationGuideLabel             matlab.ui.control.Label
        TextArea_6                     matlab.ui.control.TextArea
        TextArea_5                     matlab.ui.control.TextArea
        HowdoIusetheappLabel           matlab.ui.control.Label
        ContactUsLabel                 matlab.ui.control.Label
        Hyperlink_2                    matlab.ui.control.Hyperlink
        WhatisincludedintheHydroSharedownloadLabel  matlab.ui.control.Label
        IncludedMaterialsLabel         matlab.ui.control.Label
        HelpandSupportLabel            matlab.ui.control.Label
        Hyperlink                      matlab.ui.control.Hyperlink
        TextArea_4                     matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        tData % Data table.
        DataPackage DataEMMA %DataEMMA object with array of properties
        cElMixData (1, :) cell % Data tables for individual categories.
        cMixPCData (1, :) cell %Data tables for PCA mixture scores
        cElEMData (1, :) cell % Data tables for endmembers
        cEMPCData (1, :) cell %Data tables for PCA endmember scores
        SelectedTracers logical %logical cell for user selected tracers
        SelectedLoc logical %logical array for selected locations
        SelectedEM logical %logical array for selected endmembers
        SelectedLocations logical
        AnalyzeData AnalyzeEMMA %AnalyzeEMMA object
        Error ErrorEMMA %ErrorEMMA object
        PCA % PCA data.
        fullData % Dataset with raw and PCA data
        sortedData % Sorted data by location and date
        totalPercents % Percent contributed by each endmember
        predictedData % Predicted river concentrations based on % contributions
    end
    
    methods
        function populateListBoxes(app)
            %Updates the listboxes.
            cLocs = app.DataPackage.Locations;
            app.LocationListBox.Items = app.DataPackage.Locations(app.SelectedLoc);
            app.LocationDropDown.Items = cLocs;
            app.LocationDropDown_2.Items = cLocs;
        end
        
        function populateAxesDropDown(app)
            %Updates the x and y axes drop downs.
            cX = app.DataPackage.DataAll.Properties.VariableNames;
            cY = cX;
            %cZ = cX;
            app.XAxisDropDown_2.Items = cX;
            app.YAxisDropDown_2.Items = cY;
            %app.ZAxisDropDown.Items = cZ;            
        end

        function populatePCADropDown(app)
            %Updates the x and y axes drop downs for the PCA tab.
            cX = app.DataPackage.MixPCAData.score.Properties.VariableNames;
            cY = cX;
            cZ = cX;
            app.XAxisDropDown.Items = cX;
            app.YAxisDropDown.Items = cY;
            app.ZAxisDropDown_2.Items = cZ;
        end

        function populateResidualsDropDown(app)
            cData = app.DataPackage.Tracers(app.DataPackage.SelectedTracers);
            app.ResidualsDropDown.Items = cData;
            app.TracerDropDown.Items = cData;
            app.UITable12.Data = cData';
        end

        function ia = populateDateDropDown(app)
            %Updates the start date and end date drop downs.
            mDataType = string(app.tData.DataType);
            vDates = datetime(app.tData.SampleDate);
            vMixtureDates = vDates(mDataType == "Mixture", :);
            [vMixtureDates, ia] = unique(vMixtureDates);
            vMixtureDates = cellstr(char(vMixtureDates));
            %vDates = cellstr(vDates);
            app.StartDateDropDown.Items = vMixtureDates;
            app.EndDateDropDown.Items = vMixtureDates;
            %Set default values
            app.StartDateDropDown.Value = vMixtureDates(1);
            app.EndDateDropDown.Value = vMixtureDates(end);
        end
        function sortPCA(app)
            %Sorts out the calculated PCA data from the DataEMMA object.

            %Pull out PC data for mixture, endmembers and averaged endmembers
            mMixPC = table2array(app.DataPackage.MixPCAData.score); %mixture
            mEMPC = table2array(app.DataPackage.EMScoreMatrix); %endmembers
            mAvgEMPC = table2array(app.DataPackage.AvgEMScore); %averaged endmembers

            PC = [mMixPC; mEMPC; mAvgEMPC];
            
            %Save data into app. 
            app.PCA = array2table(PC);
        end
        function refreshPCASpecs(app)
            %Place $ explained data into table
            app.UITable10.Data = app.DataPackage.MixPCAData.explained;

            %Place coefficient data into table.
            app.UITable3.Data = app.DataPackage.MixPCAData.coeff;
            %Create the column names for the coefficients table. 
            app.UITable3.ColumnName = app.DataPackage.MixPCAData.score.Properties.VariableNames;

            %Place the PC scores into table.
            app.UITable4.Data = app.DataPackage.MixPCAData.score;

            %Get data into correctly sorted order.
            cTableSort = app.DataPackage.SelectedLocData;
                    tTableSort = cTableSort{1};
            
                    for i = 2:length(cTableSort)
                        tTableSort = [tTableSort; cTableSort{i}];
                    end

            %Add location and date names to PC score table.
            cLoc = tTableSort.SampleLocation;
            cDates = cellstr(tTableSort.SampleDate);
            cPCData = app.DataPackage.MixPCAData.score;
            cPCDataTot = [cLoc, cDates, cPCData];
            app.UITable4.Data = cPCDataTot; %Put data into PC score table

            %Add column names for PC Data Table.
            sHeaders = {'Location', 'Sample Date'};
            sPCColNames = app.DataPackage.MixPCAData.score.Properties.VariableNames;
            app.UITable4.ColumnName = [sHeaders, sPCColNames];
        end
        function populatePlotOptions(app)
            app.ColorSelectDropDown.Items = {'red', 'green', 'blue', 'cyan',...
                'magenta', 'yellow', 'black', 'deep blue', 'orange', 'mustard',...
                'deep purple', 'soft green', 'sky blue', 'cardinal red'};
            app.SymbolSelectDropDown.Items = {'circle', 'square', 'diamond',...
                'upward triangle', 'downward triangle', 'right triangle',...
                'left triangle', 'pentagram', 'hexagram'};
        end
        function refreshPopulateListbox(app)
            vCatNames = app.UITable5.Data(:, 1);
            app.LocListBox.Items = vCatNames;
        end
        function refreshEMListBox(app)
            vCatNames = app.DataPackage.TypeEM;
            app.LocationListBox.Items = vCatNames(app.SelectedEM); 
        end
        function refreshSelectedEMListBox(app)
            vCatNames = app.UITable5_2.Data(:, 1);
            %app.EndmembersinPCAListBox.Items = vCatNames;
            app.SelEMListBox.Items = vCatNames;
        end
        function calcCorrelationMatrix(app)
            mMixData = app.DataPackage.FullMixNumeric;
            corrmatrix = corrcoef(mMixData); 
            %corrmatrix = app.DataPackage.CorrMatrix;
            %corrmatrix = flipud(corrmatrix); %This flips the matrix upside down
            
            cElements = app.tData.Properties.VariableNames(12:end);
            cElements = cElements(app.SelectedTracers); 

            heatmap(app.Panel_2, corrmatrix, 'Title', 'Correlation Matrix', 'XDisplayLabels',...
                cElements, 'YDisplayLabels', cElements)

            plotmatrix(app.UIAxes4, mMixData)
        end
        function plotPCA(app)
            %Import the category data for the LOCATIONS. 
            tDataCategory = app.DataPackage.MixturePCA;
            xDataType = app.XAxisDropDown.Value;
            yDataType = app.YAxisDropDown.Value;
            zDataType = app.ZAxisDropDown_2.Value;

            for i = 1:length(app.LocListBox.Items)
            %Location (plotted one by one)
            vLoc = string(app.UITable5.Data(:, 2));
            vFindLoc = vLoc(i);
            dSelLoc = app.DataPackage.Locations(app.DataPackage.SelectedLoc);
            dPosition = find(ismember(dSelLoc, vFindLoc));
            
            %Extract the particular data necessary for plotting.
            %cVar = app.DataPackage.MixPCAData.score.Properties.VariableNames;
            cDataset = tDataCategory{dPosition};
            cXData = table2array(cDataset(:, xDataType));
            cYData = table2array(cDataset(:, yDataType));
            cZData = table2array(cDataset(:, zDataType));

            %Find the position of the category or categories selected.
            %vItems = app.LocationsinPCAListBox.Items;
            dValPosition = i;
            color = char(app.UITable5.Data(dValPosition, 6));
            symbol = char(app.UITable5.Data(dValPosition, 5));
            
            %Separate out the desired symbol for the locations
            symbolOpts = app.SymbolSelectDropDown.Items;
            vSymbolSelected = find(ismember(symbolOpts, symbol));
            vSymbolOpt = {'o', 's', 'd', '^', 'v', '>', '<', 'p', 'h'};
            newSymbol = char(vSymbolOpt(vSymbolSelected));
            
            %Separate out the desired color for the locations
            colorOpts = app.ColorSelectDropDown.Items;
            vColorSelected = find(ismember(colorOpts, color));
            vColorOpt = {'red', 'green', 'blue', 'cyan', 'magenta',...
                'yellow', 'black', '#0072BD', '#D95319', '#EDB120',...
                '#7E2F8E', '#77AC30', '#4DBEEE', '#A2142F'};
            color = char(vColorOpt(vColorSelected));
            
            %Plot the 2D data
            plot(app.UIAxes7, cXData, cYData, newSymbol, 'color', color);
            %set(h, 'MarkerFaceColor', get(h, 'Color'));
            app.UIAxes7.XLabel.String = char(xDataType);
            app.UIAxes7.YLabel.String = char(yDataType);
            app.UIAxes7.Title.String = (char(xDataType) + " vs. " + char(yDataType));

            %Set up the legend.
            l = legend(app.UIAxes7);
            l.String{dPosition} = char(vFindLoc);
            hold(app.UIAxes7, 'on')
            
            %Plot the 3D data
            % Get the z data
            plot3(app.UIAxes8, cXData, cYData, cZData, newSymbol, 'color', color);
            %set(h, 'MarkerFaceColor', get(h, 'Color'));
            app.UIAxes8.XLabel.String = char(xDataType);
            app.UIAxes8.YLabel.String = char(yDataType);
            app.UIAxes8.ZLabel.String = char(zDataType);
            %app.UIAxes8.Zlabel.String = char(zDataType);
            app.UIAxes8.Title.String = ('3D PCA');

            hold(app.UIAxes8, 'on')
            end
        end

        function plotPCAEM(app)
            %Import the category data. 
            tDataCategory = app.DataPackage.EMPCA;
            xDataType = app.XAxisDropDown.Value;
            yDataType = app.YAxisDropDown.Value;
            zDataType = app.ZAxisDropDown_2.Value;

            for i = 1:length(app.UITable5_2.Data(:, 2))
            %Endmember
            vLoc = string(app.UITable5_2.Data(:, 2));
            vFindLoc = vLoc(i);
            dSelLoc = app.DataPackage.TypeEM(app.DataPackage.SelectedEM);
            dPosition = find(ismember(dSelLoc, vFindLoc));
            
            %Extract the particular data necessary for plotting.
            cVar = app.DataPackage.MixPCAData.score.Properties.VariableNames;
            cDataset = tDataCategory{dPosition};
            cXData = table2array(cDataset(:, xDataType));
            cYData = table2array(cDataset(:, yDataType));
            cZData = table2array(cDataset(:, zDataType));

            %Find the position of the category or categories selected.
            vItems = app.LocListBox.Items;
            dValPosition = i;
            color = char(app.UITable5_2.Data(dValPosition, 6));
            symbol = char(app.UITable5_2.Data(dValPosition, 5));
            
            %Separate out the desired symbol for the locations
            symbolOpts = app.SymbolSelectDropDown.Items;
            vSymbolSelected = find(ismember(symbolOpts, symbol));
            vSymbolOpt = {'o', 's', 'd', '^', 'v', '>', '<', 'p', 'h'};
            newSymbol = char(vSymbolOpt(vSymbolSelected));
            
            %Separate out the desired color for the locations
            colorOpts = app.ColorSelectDropDown.Items;
            vColorSelected = find(ismember(colorOpts, color));
            vColorOpt = {'red', 'green', 'blue', 'cyan', 'magenta',...
                'yellow', 'black', '#0072BD', '#D95319', '#EDB120',...
                '#7E2F8E', '#77AC30', '#4DBEEE', '#A2142F'};
            color = char(vColorOpt(vColorSelected));
            
            %Plot the data
            plot(app.UIAxes7, cXData, cYData, newSymbol, 'color', color);
            %set(h, 'MarkerFaceColor', get(h, 'Color'));
            app.UIAxes7.XLabel.String = char(xDataType);
            app.UIAxes7.YLabel.String = char(yDataType);
            app.UIAxes7.Title.String = (char(xDataType) + " vs. " + char(yDataType));

            %Plot data on 3D plot
            plot3(app.UIAxes8, cXData, cYData, cZData, newSymbol, 'color', color);
            hold(app.UIAxes8, 'on')
            
            end
        end

        function plotPCAEMavg(app)
            %Import the category data. 
            tDataCategory = app.DataPackage.AvgEMPCA;
            xDataType = app.XAxisDropDown.Value;
            yDataType = app.YAxisDropDown.Value;
            zDataType = app.ZAxisDropDown_2.Value;

            for i = 1:length(app.UITable5_2.Data(:, 2))
            %Endmember
            vLoc = string(app.UITable5_2.Data(:, 2));
            vFindLoc = vLoc(i);
            dSelLoc = app.DataPackage.TypeEM(app.DataPackage.SelectedEM);
            dPosition = find(ismember(dSelLoc, vFindLoc));
            
            %Extract the particular data necessary for plotting.
            cVar = app.DataPackage.MixPCAData.score.Properties.VariableNames;
            cDataset = tDataCategory{dPosition};
            cXData = table2array(cDataset(:, xDataType));
            cYData = table2array(cDataset(:, yDataType));
            cZData = table2array(cDataset(:, zDataType));

            %Find the position of the category or categories selected.
            vItems = app.LocListBox.Items;
            dValPosition = i;
            color = char(app.UITable5_2.Data(dValPosition, 6));
            symbol = char(app.UITable5_2.Data(dValPosition, 5));
            
            %Separate out the desired symbol for the locations
            symbolOpts = app.SymbolSelectDropDown.Items;
            vSymbolSelected = find(ismember(symbolOpts, symbol));
            vSymbolOpt = {'o', 's', 'd', '^', 'v', '>', '<', 'p', 'h'};
            newSymbol = char(vSymbolOpt(vSymbolSelected));
            
            %Separate out the desired color for the locations
            colorOpts = app.ColorSelectDropDown.Items;
            vColorSelected = find(ismember(colorOpts, color));
            vColorOpt = {'red', 'green', 'blue', 'cyan', 'magenta',...
                'yellow', 'black', '#0072BD', '#D95319', '#EDB120',...
                '#7E2F8E', '#77AC30', '#4DBEEE', '#A2142F'};
            color = char(vColorOpt(vColorSelected));
            
            %Plot the data
            plot(app.UIAxes7, cXData, cYData, newSymbol, 'color', color);
            app.UIAxes7.XLabel.String = char(xDataType);
            app.UIAxes7.YLabel.String = char(yDataType);
            app.UIAxes7.Title.String = (char(xDataType) + " vs. " + char(yDataType));
            hold(app.UIAxes7, 'on')

            %Plot data on 3D plot
            plot3(app.UIAxes8, cXData, cYData, cZData, newSymbol, 'color', color);
            hold(app.UIAxes8, 'on')
            end
        end

        function plotResiduals(app)
            %Import the category data for the predicted values. 
            mCalcPrediction = app.AnalyzeData;
            tDataCategory = mCalcPrediction.PredictionLoc;
            mPrediction = mCalcPrediction.PredictedVal;
            mElData = app.DataPackage.FullMixNumeric;

            %Determine the position of the tracer of interest in the
            %dropdown.
            sAllTracers = app.TracerDropDown.Items;
            sTracer = app.TracerDropDown.Value;
            dTPosition = find(ismember(sAllTracers, sTracer));

            for i = 1:length(app.LocListBox.Items)
            %Location (plotted one by one)
            vLoc = string(app.UITable5.Data(:, 2));
            vFindLoc = vLoc(i);
            dSelLoc = app.DataPackage.Locations(app.DataPackage.SelectedLoc);
            dPosition = find(ismember(dSelLoc, vFindLoc));

            %Extract the particular data necessary for plotting.
            cVar = app.DataPackage.MixPCAData.score.Properties.VariableNames;
            mElDataLoc = app.DataPackage.FullNumericLoc{dPosition};
            cDataset = tDataCategory{dPosition};
            cXData = mElDataLoc(:, dTPosition); 
            cYData = cDataset(:, dTPosition);

            %Find the position of the category or categories selected.
            vItems = app.LocListBox.Items;
            dValPosition = i;
            color = char(app.UITable5.Data(dValPosition, 6));
            symbol = char(app.UITable5.Data(dValPosition, 5));
            
            %Separate out the desired symbol for the locations
            symbolOpts = app.SymbolSelectDropDown.Items;
            vSymbolSelected = find(ismember(symbolOpts, symbol));
            vSymbolOpt = {'o', 's', 'd', '^', 'v', '>', '<', 'p', 'h'};
            newSymbol = char(vSymbolOpt(vSymbolSelected));
            
            %Separate out the desired color for the locations
            colorOpts = app.ColorSelectDropDown.Items;
            vColorSelected = find(ismember(colorOpts, color));
            vColorOpt = {'red', 'green', 'blue', 'cyan', 'magenta',...
                'yellow', 'black', '#0072BD', '#D95319', '#EDB120',...
                '#7E2F8E', '#77AC30', '#4DBEEE', '#A2142F'};
            color = char(vColorOpt(vColorSelected));
            
            %Plot the predicted data
            plot(app.UIAxes9, cXData, cYData, newSymbol, 'color', color);
            hold(app.UIAxes9, 'on')
            app.UIAxes9.XLabel.String = 'Observed Concentration';
            app.UIAxes9.YLabel.String = 'Predicted Concentration';
            app.UIAxes9.Title.String = string(sTracer);
            hold(app.UIAxes9, 'on')
            end
        end

        function jackknifePlot(app)
            %Import the average percents and the standard deviation for the
            %error calculated. 
            mMeanPercents = app.Error.MeanPercents;
            mStdPercents = app.Error.StdPercents;

            for i = 1:length(app.UITable5_2.Data(:, 2))
            vLoc = string(app.UITable5_2.Data(:, 2));
            vFindLoc = vLoc(i);
            dSelLoc = app.DataPackage.TypeEM(app.DataPackage.SelectedEM);
            dPosition = find(ismember(dSelLoc, vFindLoc));
            mLocData = app.DataPackage.SelectedLocData{1};
            vDates = mLocData.SampleDate;
            X = categorical(vDates);

            %Take out location data:
            sLocation = app.LocationDropDown_2.Value;
            mLocations = app.DataPackage.Locations(app.DataPackage.SelectedLoc);
            dLocPosition = find(ismember(mLocations, sLocation));

            mMean = app.Error.MeanData{dLocPosition};
            mStd = app.Error.StdData{dLocPosition};

            %Calculate the upper bound and lower bound error.
            vUpperBounds = mMeanPercents + 2*(mStdPercents(:, 1));
            vLowerBounds = mMeanPercents - 2*(mStdPercents(:, 1));
            
            %Separate by location for UB.
            iRowOn = 0;
            vLocUB = cell(1, length(app.DataPackage.SelectedLocData));

            for y = 1:length(app.DataPackage.SelectedLocData)
                iRows = size(app.DataPackage.SelectedLocData{y}, 1);
                vLocUB{y} = vUpperBounds(iRowOn+1:iRowOn+iRows, :);
                iRowOn = iRowOn + iRows;
            end

            %Separate by location for LB.
            iRowOn = 0;
            vLocLB = cell(1, length(app.DataPackage.SelectedLocData));

            for y = 1:length(app.DataPackage.SelectedLocData)
                iRows = size(app.DataPackage.SelectedLocData{y}, 1);
                vLocLB{y} = vLowerBounds(iRowOn+1:iRowOn+iRows, :);
                iRowOn = iRowOn + iRows;
            end

            vSelUB = vLocUB{dLocPosition};
            vSelLB = vLocLB{dLocPosition};

            %Find the position of the category or categories selected.
            vItems = app.LocListBox.Items;
            dValPosition = i;
            color = char(app.UITable5_2.Data(dValPosition, 6));
            
            %Separate out the desired color for the locations
            colorOpts = app.ColorSelectDropDown.Items;
            vColorSelected = find(ismember(colorOpts, color));
            vColorOpt = {'red', 'green', 'blue', 'cyan', 'magenta',...
                'yellow', 'black', '#0072BD', '#D95319', '#EDB120',...
                '#7E2F8E', '#77AC30', '#4DBEEE', '#A2142F'};
            color = char(vColorOpt(vColorSelected));

            %Plot the error bar shape. 
            plot(app.UIAxes6, X, vSelUB(:, i), '-', 'color', color)
            hold(app.UIAxes6, 'on')
            plot(app.UIAxes6, X, vSelLB(:, i), '-', 'color', color)
            hold(app.UIAxes6, 'on')
            
            %Plot the data
            plot(app.UIAxes6, X, mMean(:, i), 'k--');
            hold(app.UIAxes6, 'on')
            end
            %Plot labels.
            app.UIAxes6.XLabel.String = char('Fractional Contribution w/ Error');
            app.UIAxes6.YLabel.String = char('Sample Date');
            app.UIAxes6.Title.String = (char(sLocation));
            hold(app.UIAxes6, 'on')
        end

        function retrieveMixData(app)
            app.cElMixData = app.DataPackage.SelectedLocData;
            app.cMixPCData = app.DataPackage.MixturePCA;
        end

        function retrieveEMData(app)
            app.cElEMData = app.DataPackage.SelectedEMData;
            app.cEMPCData = app.DataPackage.EMPCA;
        end
        function updateLegend(app)
            l = legend(app.UIAxes7);
            vSelLoc = string(app.UITable5.Data(:, 1))';
            vSelEM = string(app.UITable5_2.Data(:, 1))';
            vItemsAll = [vSelLoc, vSelEM];
            l.String = vItemsAll;
        end
        function predLegend(app)
            l = legend(app.UIAxes9);
            vSelLoc = string(app.UITable5.Data(:, 1))';
            vItemsAll = [vSelLoc, "Trendline", "Reference Line"];
            l.String = vItemsAll;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.OpenTool.icon = fullfile(matlabroot, 'toolbox', 'matlab', 'icons', 'file_open.png');
            %app.SaveTool.icon = fullfile(matlabroot, 'toolbox', 'matlab', 'icons', 'file_save.png');
            app.Lamp.Color = "red";
            drawnow()
        end

        % Button pushed function: LoadDatasetButton
        function LoadDatasetButtonPushed(app, event)
            %Navigate to file with dialogue box and get path.
            app.UIFigure.Visible = 'off';
            [sFile, sPath, iIdx] = uigetfile('*.xlsx', 'Select Database File');
            sFullPath = fullfile(sPath, sFile);
            app.UIFigure.Visible = 'on';

            app.DataPackage = DataEMMA(sFullPath);
            
            %Quit dialogue if Cancel is pressed.
            if isequal(sFile, 0) || iIdx == 0
                return;
            end
            
            %Get cleaned data from DataEMMA object. 
            app.tData = app.DataPackage.DataAll;
            %Get column names for main data table.
            cVar = app.DataPackage.DataAll.Properties.VariableNames;
            app.SelectedTracers = app.DataPackage.SelectedTracers;
            
            %Sort the PCA data
            app.sortPCA;
            
            %Add PCA table to full table
            %app.fullData = [app.tData app.PCA]; %This isn't going to work
            %anymore since we're creating averaged endmembers inside the
            %app. 
           
            %Put data into UITable.
            app.UITable.Data = app.tData;
            app.UITable.ColumnName = cVar;
            app.UITable.ColumnEditable = true;

            %Put tracer info into first column on tracer selection page.
            bTracers = app.DataPackage.SelectedTracers';
            cVar = string(cVar(12:end))';
            app.UITable11.Data = [cVar bTracers];
            app.UITable11.ColumnName = {'Potential Tracers', 'Checkbox'};
            app.UITable11.ColumnFormat = {'char', 'logical'};
            app.UITable11.ColumnEditable = [true, true];

            %Put tracer info into first column on tracer selection page.
            app.UITable9.Data = [cVar bTracers];
            app.UITable9.ColumnName = {'Potential Tracers', 'Checkbox'};
            app.UITable9.ColumnFormat = {'char', 'logical'};
            app.UITable9.ColumnEditable = [true, true];

            %Put location information into data picker table.
            cLocations = app.DataPackage.Locations';
            bSelLoc = app.DataPackage.SelectedLoc';
            sLoc = string(cLocations);
            app.UITable13.Data = [sLoc bSelLoc];
            app.UITable13.ColumnName = {'Locations', 'Checkbox'};
            app.UITable13.ColumnFormat = {'char', 'logical'};
            app.UITable13.ColumnEditable = [true, true];

            %Put location information into data picker table.
            cEndmembers = app.DataPackage.TypeEM';
            bSelEM = app.DataPackage.SelectedEM';
            sEM = string(cEndmembers);
            app.UITable15.Data = [sEM bSelEM];
            app.UITable15.ColumnName = {'Endmembers', 'Checkbox'};
            app.UITable15.ColumnFormat = {'char', 'logical'};
            app.UITable15.ColumnEditable = [true, true];

            %Set defaults
            app.SelectedLoc = app.DataPackage.SelectedLoc;
            app.SelectedEM = app.DataPackage.SelectedEM;

            %Make the PC options box disappear:
            app.USpaceCalculationPreferencesButtonGroup.Visible = 'off';
            
            %Update Listboxes and PCA table.
            app.populateListBoxes;
            app.populatePCADropDown;
            app.populateDateDropDown;
            app.populateAxesDropDown;
            app.populateResidualsDropDown;
            app.populatePlotOptions;
            app.refreshPCASpecs;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %Put the edited data back in the stored table.
            app.tData = app.UITable.Data;
            
        end

        % Button pushed function: CreateCategoryButton
        function CreateCategoryButtonPushed(app, event)
            switch app.DataTypeButtonGroup.SelectedObject.Text
            
                case 'Main'
                    % Create the data inputs for the tables.
                    vCatName = char(app.CategoryNameEditField.Value);
                    vLocation = char(app.LocationListBox.Value);
                    vStartDate = char(app.StartDateDropDown.Value);
                    vEndDate = char(app.EndDateDropDown.Value);
                    vSymbol = char(app.SymbolSelectDropDown.Value);
                    vColor = char(app.ColorSelectDropDown.Value);
                    
                    newRow = {vCatName, vLocation, vStartDate, vEndDate, vSymbol, vColor};
                    
                    %hTable: handle to the table
                    %newRow : the row of data you want to add to the table.
                    oldData = get(app.UITable5, 'Data');
                    newData = [oldData; newRow];
                    app.UITable5.Data = newData;
                    %app.UITable17.Data = app.UITable5.Data; %Update the table on the PC tab
                    
                    %Refresh the listbox for Categories
                    app.refreshPopulateListbox
                    app.retrieveMixData

                case 'Endmember'
                    % Create the data inputs for the tables.
                    vCatName = char(app.CategoryNameEditField.Value);
                    vLocation = char(app.LocationListBox.Value);
                    vStartDate = char(app.StartDateDropDown.Value);
                    vEndDate = char(app.EndDateDropDown.Value);
                    vSymbol = char(app.SymbolSelectDropDown.Value);
                    vColor = char(app.ColorSelectDropDown.Value);
                    
                    newRow = {vCatName, vLocation, vStartDate, vEndDate, vSymbol, vColor};
                    
                    %hTable: handle to the table
                    %newRow : the row of data you want to add to the table.
                    oldData = get(app.UITable5_2, 'Data');
                    newData = [oldData; newRow];
                    app.UITable5_2.Data = newData;
                    
                    %Refresh the listbox for Categories
                    app.retrieveEMData
                    app.refreshSelectedEMListBox
            end

        end

        % Button pushed function: SavePreferencesButton
        function SavePreferencesButtonPushed(app, event)
            sPreferences = struct('SelLoc', {app.SelectedLoc}, 'SelEM', {app.SelectedEM}, ...
                'StartDate', {app.StartDateDropDown.Value}, 'EndDate', {app.EndDateDropDown.Value}, ...
                'MixOps', {app.UITable5.Data}, 'EMOps', {app.UITable5_2.Data});


            userInput = inputdlg('Input a name for your data.');
            save(char(userInput), 'sPreferences')
            msgbox("Table data has been saved to a .mat file to the path.");

        end

        % Button pushed function: LoadPreferencesButton
        function LoadPreferencesButtonPushed(app, event)
        [file,path] = uigetfile('*.mat', 'Select table file');
            if isequal(file,0)
             fprintf('User selected Cancel.\n');
             return;
            else
             sFullPath = fullfile(path, file);
             table = load(sFullPath);
            end
        
        % Table saves as a struct, and the 1x1 struct inside is called
        % tableSave
        app.UITable13.Data(:, 2) = table.sPreferences.SelLoc;
        app.UITable15.Data(:, 2) = table.sPreferences.SelEM;
        app.StartDateDropDown.Value = table.sPreferences.StartDate;
        app.EndDateDropDown.Value = table.sPreferences.EndDate;
        app.UITable5.Data = table.sPreferences.MixOps;
        app.UITable5_2.Data = table.sPreferences.EMOps;
        
        %Refresh the listbox for Categories
            %Update Listboxes and PCA table.
            app.populateListBoxes;
            app.populatePCADropDown;
            app.populateDateDropDown;
            app.populateAxesDropDown;
            app.populateResidualsDropDown;
            app.populatePlotOptions;
            %app.calcCorrelationMatrix;
            app.refreshPCASpecs;

            %Refresh the listbox for Categories
            app.refreshPopulateListbox
            app.retrieveMixData
            app.retrieveEMData
            app.refreshSelectedEMListBox

            %Change set preferences lamp to green.
            app.Lamp.Color = 'green';
        
        end

        % Button pushed function: SavePCDataButton
        function SavePCDataButtonPushed(app, event)
            %Save PC score table.
            sPCInput = inputdlg('Input a name for the PC Score data.');
            sPCFileName = string(sPCInput) + '.txt';
            writetable(app.UITable4.Data, sPCFileName)

            %Save coefficient matrix table.
            sCoeffInput = inputdlg('Input a name for the coefficient matrix.');
            sCoeffFileName = string(sCoeffInput) + '.txt';
            writematrix(app.UITable3.Data, sCoeffFileName)

            msgbox("PC data and coefficient matrix have been saved (as .txt) to the path.");
            
        end

        % Button pushed function: SaveMixingResultsButton
        function SaveMixingResultsButtonPushed(app, event)
            %Save mixing results table.
            sMixInput = inputdlg('Input a name for the fractional contribution data.');
            sMixFileName = string(sMixInput) + '.txt';
            writecell(app.UITable6.Data, sMixFileName)

            msgbox("Mixing results have been saved (as .txt) to the path.");
        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            switch app.DataTypeButtonGroup.SelectedObject.Text

                case 'Main'     
                    %Refresh the listbox for Categories
                    app.populateListBoxes

                case 'Endmember'
                    %Refresh list box options with endmember names
                    app.refreshEMListBox
            end
            
        end

        % Value changed function: XAxisDropDown_2, YAxisDropDown_2
        function TimeSeriesAxes(app, event)
                %Category data. 
                tDataCategory = app.cElMixData;
                xDataType = app.XAxisDropDown_2.Value;
                yDataType = app.YAxisDropDown_2.Value;

            for i = 1:length(app.UITable5.Data(:, 2))
                %Location (plotted one by one)
                vLoc = string(app.UITable5.Data(:, 2));
                vFindLoc = vLoc(i);
                dSelLoc = app.DataPackage.Locations(app.DataPackage.SelectedLoc);
                dPosition = find(ismember(dSelLoc, vFindLoc));
                
                %Extract the particular data necessary for plotting.
                cDataset = tDataCategory{dPosition};
                cXData = table2array(cDataset(:, xDataType));
                cYData = table2array(cDataset(:, yDataType));
    
                %Find the position of the category or categories selected.
                dValPosition = i;
                color = char(app.UITable5.Data(dValPosition, 6));
                
                %Separate out the desired color for the locations
                colorOpts = app.ColorSelectDropDown.Items;
                vColorSelected = find(ismember(colorOpts, color));
                vColorOpt = {'red', 'green', 'blue', 'cyan', 'magenta',...
                    'yellow', 'black', '#0072BD', '#D95319', '#EDB120',...
                    '#7E2F8E', '#77AC30', '#4DBEEE', '#A2142F'};
                color = char(vColorOpt(vColorSelected));

                %Plot the data
                plot(app.UIAxes_2, cXData, cYData, '-o', 'color', color)
                app.UIAxes_2.XLabel.String = char(xDataType);
                app.UIAxes_2.YLabel.String = char(yDataType);
                app.UIAxes_2.Title.String = (char(yDataType));

                %Set up the legend.
                l = legend(app.UIAxes_2);
                l.String{i} = char(vFindLoc);
                hold(app.UIAxes_2, 'on')
            end

        end

        % Button pushed function: UpdateAxesLabelsButton
        function UpdateAxesLabelsButtonPushed(app, event)
            xInput = app.XAxisLabelEditField.Value;
            yInput = app.YAxisLabelEditField.Value;
            title = app.TitleEditField.Value;
            
            %This makes it so the user can create whatever names they want
            %for the xlabel and ylabel, and by default the name of the plot
            %is the same name as the y axis label.
            app.UIAxes_2.XLabel.String = char(xInput);
            app.UIAxes_2.YLabel.String = char(yInput);
            app.UIAxes_2.Title.String = char(title);
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = string(app.TabGroup.SelectedTab.Title);
            
            if selectedTab == string(app.PCATab.Title)
                %Code here runs when the PCA tab is selected

                %Clear the plot.
                hold(app.UIAxes7, 'off')
                cla(app.UIAxes7)

                %Plot stuff.
                app.plotPCA
                app.plotPCAEM
                app.SetPreferencesButtonPushed
                app.retrieveMixData
                
                %Refresh the legend.
                app.updateLegend

            else
                %Nothing happens
            end
        end

        % Value changed function: XAxisDropDown, YAxisDropDown, 
        % ...and 1 other component
        function RefreshPCA(app, event)
            %Refresh the plots
            app.plotPCA
            app.plotPCAEM
            
        end

        % Selection changed function: EndmemberButtonGroup
        function EndmemberButtonGroupSelectionChanged(app, event)
            selectedButton = app.EndmemberButtonGroup.SelectedObject.Text;
            
            switch selectedButton
            
                case 'Endmembers (all)'     
                    %Refresh plots and plot with all mixture data and
                    %all selected endmember data.
                    %Clear the data first:
                    app.ClearPlotButtonPushed
                    app.plotPCA
                    app.plotPCAEM
                    app.updateLegend

                case 'Averaged Endmembers'
                    %Clear plots and plot all mixture data with averaged
                    %endmember data.
                    app.ClearPlotButtonPushed
                    app.plotPCA
                    app.plotPCAEMavg
                    app.updateLegend
            end

        end

        % Button pushed function: ClearPlotButton_2, ClearPlotButton_4, 
        % ...and 2 other components
        function ClearPlotButtonPushed(app, event)
            %Clear the axes on the 2D plot.
            %hold(app.UIAxes_3, 'off')
            %cla(app.UIAxes_3) 
            
            %Clear the axes on the 2D PCA plot.
            hold(app.UIAxes7, 'off')
            cla(app.UIAxes7)

            %Clear the axes on the 3D PCA plot.
            hold(app.UIAxes8, 'off')
            cla(app.UIAxes8)
            
            %Clear the axes on the time series plot.
            hold(app.UIAxes_2, 'off')
            cla(app.UIAxes_2)

            %Clear the axes on the predicted v. observed values plot.
            hold(app.UIAxes9, 'off')
            cla(app.UIAxes9)

            %Clear the axes on the jackknife error plot.
            hold(app.UIAxes6, 'off')
            cla(app.UIAxes6)

        end

        % Cell selection callback: UITable5
        function UITable5CellSelection(app, event)
            indices = event.Indices;
            
            % Delete the selected row for the location data on the create 
            % categories page.  
            app.UITable5.Data(indices(1), :) = [];

            app.refreshPopulateListbox
        end

        % Cell selection callback: UITable5_2
        function UITable5_2CellSelection(app, event)
            indices = event.Indices;
            
            % Delete the selected row for the endmember data on the create
            % categories page.
            app.UITable5_2.Data(indices(1), :) = [];

            app.refreshSelectedEMListBox
            
        end

        % Callback function: CalculateButton, PlottingOptionsButtonGroup
        function ResidualPlotRefresh(app, event)
            selectedButton = app.PlottingOptionsButtonGroup.SelectedObject;
            
            mMixData = app.DataPackage.FullMixNumeric; 
            mZData = app.DataPackage.FullMixSelectedZ;
            mElements = app.ResidualsDropDown.Items;
            mSelectedElement = app.ResidualsDropDown.Value;
            
            % Create either a scatter plot or histogram, based on selection
            switch app.PlottingOptionsButtonGroup.SelectedObject.Text
                
                case 'Residuals'
                    % Build a residual plot for each element
                    cla(app.UIAxes2)
                    reset(app.UIAxes2)
                    dPosition = find(ismember(mElements, mSelectedElement));
                    [residuals, reconstructed] = pcares(mZData, app.ofPCsSpinner.Value);

                    %Convert zscored residual values back to concentrations
                    iTracers = size(residuals, 2);
                    mAdjusted = zeros(size(residuals));
            
                        for i = 1:iTracers
                            mAdjusted(:, i) = reconstructed(:, i) * app.DataPackage.sigma(i);
                            mAdjusted(:, i) = mAdjusted(:, i) + app.DataPackage.mu(i);
                        end
            
                    mPredicted = mAdjusted - mMixData;
                    plot(app.UIAxes2, mMixData(:, dPosition), mPredicted(:, dPosition), 'o')
                    app.UIAxes2.XLabel.String = 'Original Concentration (mg/L)';
                    app.UIAxes2.YLabel.String = 'Residual';
                    app.UIAxes2.Title.String = mSelectedElement;
                    
                case 'Scree Plot'
                    % Create a Scree Plot to view which PC's to retain.
                    explained = app.DataPackage.MixPCAData.explained;
                    cla(app.UIAxes2)
                    reset(app.UIAxes2)
                    % Figure settings

                    % 'AutoResizeChildren' needs to be set off for the
                    % panel the figure is in, not the figure itself. 
                    pareto(app.UIAxes2, explained)
                    app.UIAxes2.XLabel.String = 'Principal Component';
                    app.UIAxes2.YLabel.String = 'Percent Explained (%)';
            end
        end

        % Button pushed function: CalculateFractionalContributionsButton
        function PerContributionsButtonPushed(app, event)
            %Change the lamp color.
            app.CalculationStatusLamp.Color = 'red';
            drawnow();

            selectedButton = app.MixingCalculationPreferencesButtonGroup.SelectedObject.Text;
            
            switch selectedButton
            
                case 'Tracer Space'   
                    %Run the thing (aka calculate fractional
                    %contributions).
                    app.AnalyzeData = AnalyzeEMMA(app.DataPackage);
            
                    cTableSort = app.DataPackage.SelectedLocData;
                    tTableSort = cTableSort{1};
            
                    for i = 2:length(cTableSort)
                        tTableSort = [tTableSort; cTableSort{i}];
                    end
            
                    %Change the lamp color.
                    app.CalculationStatusLamp.Color = 'green';
                    drawnow();

                    cLoc = tTableSort.SampleLocation;
                    cDates = cellstr(tTableSort.SampleDate);
                    cFracData = num2cell(app.AnalyzeData.MixturePercentages);
                    cFracDataTot = [cLoc, cDates, cFracData];
            
                    app.UITable6.Data = cFracDataTot;
                    sHeaders = {'Location', 'Sample Date'};
                    sEndmembers = app.DataPackage.TypeEM(app.DataPackage.SelectedEM);
                    app.UITable6.ColumnName = [sHeaders, sEndmembers];

                    case 'U-Space (not recommended)'
                        %In case the user wants to calculate in U-space...
                        %for some reason
                        OpsPC = app.USpaceCalculationPreferencesButtonGroup.SelectedObject.Text;

                        switch OpsPC

                            case '2 PC'
                                %2 PC case
                                mPCScore = table2array(app.DataPackage.MixPCAData.score);
                                mB = mPCScore(:, 1:2);
                                mEMScore = table2array(app.DataPackage.AvgEMScore);
                                mA = mEMScore(:, 1:2);

                                app.AnalyzeData = AnalyzeEMMA(app.DataPackage); %added

                                set(app.AnalyzeData, 'EMData', mA)
                                set(app.AnalyzeData, 'MixData', mB)

                                %Change the lamp color.
                                app.CalculationStatusLamp.Color = 'green';
                                drawnow();

                                cTableSort = app.DataPackage.SelectedLocData;
                                tTableSort = cTableSort{1};
                        
                                for i = 2:length(cTableSort)
                                    tTableSort = [tTableSort; cTableSort{i}];
                                end
                        
                                cLoc = tTableSort.SampleLocation;
                                cDates = cellstr(tTableSort.SampleDate);
                                cFracData = num2cell(app.AnalyzeData.MixturePercentages);
                                cFracDataTot = [cLoc, cDates, cFracData];
                        
                                app.UITable6.Data = cFracDataTot;
                                sHeaders = {'Location', 'Sample Date'};
                                sEndmembers = app.DataPackage.TypeEM(app.DataPackage.SelectedEM);
                                app.UITable6.ColumnName = [sHeaders, sEndmembers];

                            case '3 PC'
                                %3 PC case
                                mPCScore = table2array(app.DataPackage.MixPCAData.score);
                                mB = mPCScore(:, 1:3);
                                mEMScore = table2array(app.DataPackage.AvgEMScore);
                                mA = mEMScore(:, 1:3);

                                app.AnalyzeData = AnalyzeEMMA(app.DataPackage); %added

                                set(app.AnalyzeData, 'EMData', mA)
                                set(app.AnalyzeData, 'MixData', mB)

                                %Change the lamp color.
                                app.CalculationStatusLamp.Color = 'green';
                                drawnow();

                                cTableSort = app.DataPackage.SelectedLocData;
                                tTableSort = cTableSort{1};
                        
                                for i = 2:length(cTableSort)
                                    tTableSort = [tTableSort; cTableSort{i}];
                                end
                        
                                cLoc = tTableSort.SampleLocation;
                                cDates = cellstr(tTableSort.SampleDate);
                                cFracData = num2cell(app.AnalyzeData.MixturePercentages);
                                cFracDataTot = [cLoc, cDates, cFracData];
                        
                                app.UITable6.Data = cFracDataTot;
                                sHeaders = {'Location', 'Sample Date'};
                                sEndmembers = app.DataPackage.TypeEM(app.DataPackage.SelectedEM);
                                app.UITable6.ColumnName = [sHeaders, sEndmembers];
                        end
            end


        end

        % Button pushed function: PlotFractionalContributionOverTimeButton
        function RefreshPerContributionsPlot(app, event)
            
            mFractions = app.AnalyzeData.FracData;
            sLocation = string(app.LocationDropDown.Value);
            mDates = app.DataPackage.SelectedLocData{1};
            mDates = mDates.SampleDate;
            mLocations = app.DataPackage.Locations(app.DataPackage.SelectedLoc);
            dPosition = find(ismember(mLocations, sLocation));
            pcLoc = mFractions{dPosition};
            X = categorical(mDates);
            
            %Plot the percent contributions in a stacked bar graph.
            bar(app.UIAxes5, X, pcLoc, 'stacked')
            app.UIAxes5.Title.String = "Fractional Contribution by Location";
            app.UIAxes5.XLabel.String = "Dates";
            app.UIAxes5.YLabel.String = "Fractional Contribution";
            app.UIAxes5.YLim = [0 1];
            l = legend(app.UIAxes5);
            l.String = string(app.DataPackage.TypeEM(app.DataPackage.SelectedEM));
            
        end

        % Button pushed function: CalculatePercentErrorButton
        function JackknifingCalculation(app, event)
            %Make the lamp color go red.
            app.ErrorCalculationStatusLamp.Color = "red";
            drawnow();
            
            %Send DataEMMA and AnalyzeEMMA packages to the ErrorEMMA
            %package to calculate the error within the endmember datasets.
            app.Error = ErrorEMMA(app.DataPackage, app.AnalyzeData);
            
            %%
            %Add the mean and standard deviation data to the tables, and
            %update the time elapsed error with the time it took to
            %calculate the error.
            app.UITable7.Data = app.Error.MeanPercents;
            app.UITable8.Data = app.Error.StdPercents;
            app.TimeElapsedminTextArea.Value = string(app.Error.Time/60);
            
            %Change the lamp color back to green.
            app.ErrorCalculationStatusLamp.Color = "green";
            drawnow();
        end

        % Button pushed function: PlotErrorButton
        function PlotErrorButtonPushed(app, event)
            
            app.jackknifePlot

            
        end

        % Button pushed function: SaveJackknifeDataButton
        function SaveJackknifeDataButtonPushed(app, event)
            %Save averaged fractional conribution table.
            sFracInput = inputdlg('Input a name for the jackknifed fractional contribution data.');
            sFracFileName = string(sFracInput) + '.txt';
            writetable(app.UITable7.Data, sFracFileName)

            %Save coefficient matrix table.
            sStdInput = inputdlg('Input a name for the jackknifed standard deviation data.');
            sStdFileName = string(sStdInput) + '.txt';
            writematrix(app.UITable8.Data, sStdFileName)

            msgbox("Fractional contribution and standard deviation matrices have been saved (as .csv) to the path.");
        end

        % Cell edit callback: UITable11, UITable9
        function UITable11CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            newData = str2double(newData);
            
            %This code is the only way I could get the checkboxes for the
            %selected tracers to work. Essentially there's a stupid thing
            %in MATLAB where if you create a logical checkbox column, you
            %have to give it a logical vector that turns it into true or
            %false but if you check the box or uncheck it, the return value
            %is 0 or 1, making the value of the column useless because it
            %can no longer be read as a logical since there's a mixture of
            %numeric and character data. So dumb, but whatever.
            if newData == 1
                app.UITable11.Data(indices(1), indices(2)) = 'true';
            elseif newData == 0
                app.UITable11.Data(indices(1), indices(2)) = 'false';
            else
                app.UITable11.Data(indices(1), indices(2)) = 'error';
            end

            bSelectedTracers = app.UITable11.Data(:, 2);
            app.SelectedTracers = strcmp(bSelectedTracers, 'true');

            %This makes the DataEMMA.m file run again but with the selected
            %tracers being fed in changed. 
            set(app.DataPackage, 'SelectedTracers', app.SelectedTracers)

            %All of these update places around the app that are affected by
            %the list of tracers being changed uses.
            app.UITable9.Data = app.UITable11.Data;
            %app.calcCorrelationMatrix
            app.ClearPlotButtonPushed
            app.plotPCA
            app.plotPCAEMavg
            app.refreshPCASpecs
            app.populateResidualsDropDown
            app.populatePCADropDown
            app.updateLegend
            
        end

        % Cell edit callback: UITable13
        function UITable13CellEdit(app, event)
            indices = event.Indices;
            newData = str2double(event.NewData);
            
            %See note for UITable11CellEdit. Yeah, I had to fix this for
            %EVERY checkbox table in the app. Nevertheless, we persisted
            %for the sake of the user... 
            if newData == 1
                app.UITable13.Data(indices(1), indices(2)) = 'true';
            elseif newData == 0
                app.UITable13.Data(indices(1), indices(2)) = 'false';
            else
                app.UITable13.Data(indices(1), indices(2)) = 'error';
            end

            bSelectedLoc = app.UITable13.Data(:, 2);
            bSelectedLoc = strcmp(bSelectedLoc, 'true')';
            app.SelectedLoc = bSelectedLoc;

            set(app.DataPackage, 'SelectedLoc', bSelectedLoc)

            app.populateListBoxes
            app.refreshPCASpecs
        end

        % Cell edit callback: UITable15
        function UITable15CellEdit(app, event)
            indices = event.Indices;
            newData = str2double(event.NewData);
            
            %See note for UITable11CellEdit
            if newData == 1
                app.UITable15.Data(indices(1), indices(2)) = 'true';
            elseif newData == 0
                app.UITable15.Data(indices(1), indices(2)) = 'false';
            else
                app.UITable15.Data(indices(1), indices(2)) = 'error';
            end

            bSelectedEM = app.UITable15.Data(:, 2);
            bSelectedEM = strcmp(bSelectedEM, 'true')';
            app.SelectedEM = bSelectedEM;

            set(app.DataPackage, 'SelectedEM', bSelectedEM)

            app.populateListBoxes
            app.refreshPCASpecs
        end

        % Button pushed function: SetPreferencesButton
        function SetPreferencesButtonPushed(app, event)
            %Set the locations, endmembers, and dates selected and send it
            %back through the DataEMMA.m to calculate the PCA scores etc. 

            dNewStartDate = datetime(app.StartDateDropDown.Value);
            dNewEndDate = datetime(app.EndDateDropDown.Value);
            
            bSelectedLoc = app.SelectedLoc;
            bSelectedEM = app.SelectedEM;

            set(app.DataPackage, 'StartDate', dNewStartDate)
            set(app.DataPackage, 'EndDate', dNewEndDate)

            app.Lamp.Color = "green";

            app.refreshPCASpecs
        
        end

        % Button pushed function: PlotPredictionButton
        function PlotPredictionButtonPushed(app, event)
            %Import fractional contribution data.
            mCalcPrediction = app.AnalyzeData;
            mPrediction = mCalcPrediction.PredictedVal;
            mElData = app.DataPackage.FullMixNumeric;
            
            %Plot the predictions
            app.plotResiduals

            %Determine the position of the tracer of interest in the
            %dropdown.
            sAllTracers = app.TracerDropDown.Items;
            sTracer = app.TracerDropDown.Value;
            dTPosition = find(ismember(sAllTracers, sTracer));

            %Get coefficient data for R^2, slope-1, and y-intercept
            x = mElData(:, dTPosition);
            y = mPrediction(:, dTPosition);
            % Get coefficients of a line fit through the data.
            coefficients = polyfit(x, y, 1);
            % Create a new x axis with exactly 1000 points (or whatever you want).
            xFit = linspace(min(x), max(x), 1000);
            % Get the estimated yFit value for each of those 1000 new x locations.
            yFit = polyval(coefficients , xFit);
            
            %Plot the reference line. 
            hold(app.UIAxes9, 'on')
            plot(app.UIAxes9, xFit, yFit, 'k-', 'LineWidth', 2); % Plot fitted line.
            hold(app.UIAxes9, "on")
            plot(app.UIAxes9, [min([xlim(app.UIAxes9) ylim(app.UIAxes9)]) max([xlim(app.UIAxes9) ylim(app.UIAxes9)])], [min([xlim(app.UIAxes9) ylim(app.UIAxes9)]) max([xlim(app.UIAxes9) ylim(app.UIAxes9)])], '--r')
            app.predLegend

            % Calculate R^2, slope-1, and intercept
            %A perfect fit would be indicated from a R^2 = 1, slope-1 = 0, and
            %intercept = 0 (Christopherson, 1992). 

            %Determine the position of the tracer of interest in the
            %dropdown.
            sAllTracers = app.TracerDropDown.Items;
            sTracer = app.TracerDropDown.Value;
            dTPosition = find(ismember(sAllTracers, sTracer));

            %R^2
            vR = corrcoef(mElData(:, dTPosition), mPrediction(:, dTPosition));
            vR = vR(1, 2);
            app.UITable16.Data(:, 1) = vR*vR;
            
            %Slope-1
            app.UITable16.Data(:, 2) = coefficients(1) - 1;
            
            %Intercept
            app.UITable16.Data(:, 3) = coefficients(2); %this is the y-intercept

        end

        % Selection changed function: 
        % MixingCalculationPreferencesButtonGroup
        function MixingCalculationPreferencesButtonGroupSelectionChanged(app, event)
            selectedButton = app.MixingCalculationPreferencesButtonGroup.SelectedObject.Text;
            
            switch selectedButton
                case 'Tracer Space'
                    %Hide the PC options 
                    app.USpaceCalculationPreferencesButtonGroup.Visible = 'off';

                case 'U-Space (not recommended)'
                    %Make PC options appear
                    app.USpaceCalculationPreferencesButtonGroup.Visible = 'on';
            end
        end

        % Button pushed function: CreateCorrelationMatrixButton
        function CreateCorrelationMatrixButtonPushed(app, event)
            %Make the lamp color go red.
            app.PlotStatusLamp.Color = "red";
            drawnow();

            app.calcCorrelationMatrix

            app.PlotStatusLamp.Color = "green";
            drawnow();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.IntroPageTab = uitab(app.TabGroup);
            app.IntroPageTab.Title = 'Intro Page';
            app.Panel_6 = uipanel(app.IntroPageTab);
            app.Panel_7 = uipanel(app.Panel_6);
            app.Image = uiimage(app.Panel_7);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'EMMA1.png');
            app.Image_2 = uiimage(app.Panel_7);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'EMMA2.png');
            app.Image_3 = uiimage(app.Panel_7);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'EMMA3.png');
            app.Image_4 = uiimage(app.Panel_7);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'EMMA4.png');
            app.Image_5 = uiimage(app.Panel_7);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'EMMA5.png');
            app.Image_6 = uiimage(app.Panel_7);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'EMMA6.png');
            app.EMMALABv10Label = uilabel(app.Panel_7);
            app.EMMALABv10Label.Text = 'EMMALAB v. 1.0';
            app.TextArea_3 = uitextarea(app.Panel_7);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Created By: '; 'Alyssa Thompson, BYU Geological Sciences'; 'Barry Bickmore, BYU Geological Sciences'; 'Emily Evans, BYU Mathematics'; 'Greg Carling, BYU Geological Sciences'; ''; 'This program was developed with support from National Science Foundation grants EAR-2012093 and EAR-2005432.'; ''; 'For information on how to use the app, select the ''Help'' tab to view training resources. If you publish work that relied on this program, please cite following the instructions also found under the ''Help'' tab.'; ''; 'This app is shared under the Creative Commons Attribution CC BY.'; ''; ''; ''};
            app.Image2 = uiimage(app.Panel_7);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'BYU_Geo_Logo.png');
            app.Image3 = uiimage(app.Panel_7);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'NSF_Official_logo_High_Res_1200ppi.png');
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.DataPickerPanel = uipanel(app.DataTab);
            app.DataPickerPanel.Title = 'Data Picker';
            app.StartDateDropDownLabel = uilabel(app.DataPickerPanel);
            app.StartDateDropDownLabel.Text = 'Start Date';
            app.StartDateDropDown = uidropdown(app.DataPickerPanel);
            app.StartDateDropDown.Items = {'26-Feb-2014', '7-Mar-2014', '23-Jun-2015', '4-Jul-2016'};
            app.StartDateDropDown.Value = '26-Feb-2014';
            app.EndDateDropDownLabel = uilabel(app.DataPickerPanel);
            app.EndDateDropDownLabel.Text = 'End Date';
            app.EndDateDropDown = uidropdown(app.DataPickerPanel);
            app.EndDateDropDown.Items = {'24-Nov-2020', '4-Mar-2014', '19-Jun-2015', '23-Aug-2020'};
            app.EndDateDropDown.Value = '24-Nov-2020';
            app.UITable13 = uitable(app.DataPickerPanel);
            app.UITable13.ColumnName = {'Locations'; 'Checkbox'};
            app.UITable13.RowName = {};
            app.UITable13.ColumnEditable = true;
            app.UITable13.CellEditCallback = createCallbackFcn(app, @UITable13CellEdit, true);
            app.SetPreferencesButton = uibutton(app.DataPickerPanel, 'push');
            app.SetPreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @SetPreferencesButtonPushed, true);
            app.SetPreferencesButton.Text = 'Set Preferences';
            app.UITable15 = uitable(app.DataPickerPanel);
            app.UITable15.ColumnName = {'Endmembers'; 'Checkbox'};
            app.UITable15.RowName = {};
            app.UITable15.CellEditCallback = createCallbackFcn(app, @UITable15CellEdit, true);
            app.LoadDatasetButton = uibutton(app.DataPickerPanel, 'push');
            app.LoadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetButtonPushed, true);
            app.LoadDatasetButton.Tooltip = {'Use the template to organize your data and then load it here'};
            app.LoadDatasetButton.Text = 'Load Dataset...';
            app.Lamp = uilamp(app.DataPickerPanel);
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.PlotOptionsTab = uitab(app.TabGroup);
            app.PlotOptionsTab.Title = 'Plot Options';
            app.UITable5 = uitable(app.PlotOptionsTab);
            app.UITable5.ColumnName = {'Category Name'; 'Location'; 'Start Date'; 'End Date'; 'Symbol'; 'Color'};
            app.UITable5.RowName = {};
            app.UITable5.CellSelectionCallback = createCallbackFcn(app, @UITable5CellSelection, true);
            app.LocationPanel = uipanel(app.PlotOptionsTab);
            app.LocationPanel.Title = 'Location';
            app.LocationListBoxLabel = uilabel(app.LocationPanel);
            app.LocationListBoxLabel.Text = 'Location';
            app.LocationListBox = uilistbox(app.LocationPanel);
            app.LocationListBox.Items = {'PR Soapstone', 'PR Woodland', 'PR Hailstone', 'Hailstone'};
            app.LocationListBox.Multiselect = 'on';
            app.LocationListBox.Value = {'PR Soapstone'};
            app.CategoryDetailsPanel = uipanel(app.PlotOptionsTab);
            app.CategoryDetailsPanel.Title = 'Category Details';
            app.SymbolSelectDropDownLabel = uilabel(app.CategoryDetailsPanel);
            app.SymbolSelectDropDownLabel.Text = 'Symbol Select';
            app.SymbolSelectDropDown = uidropdown(app.CategoryDetailsPanel);
            app.SymbolSelectDropDown.Items = {'Circle', 'Square', 'Diamond', 'Star'};
            app.SymbolSelectDropDown.Value = 'Circle';
            app.ColorSelectDropDownLabel = uilabel(app.CategoryDetailsPanel);
            app.ColorSelectDropDownLabel.Text = 'Color Select';
            app.ColorSelectDropDown = uidropdown(app.CategoryDetailsPanel);
            app.ColorSelectDropDown.Items = {'Red', 'Green', 'Blue', 'Cyan'};
            app.ColorSelectDropDown.Value = 'Red';
            app.CategoryNameEditFieldLabel = uilabel(app.CategoryDetailsPanel);
            app.CategoryNameEditFieldLabel.Text = 'Category Name';
            app.CategoryNameEditField = uieditfield(app.CategoryDetailsPanel, 'text');
            app.CreateCategoryButton = uibutton(app.CategoryDetailsPanel, 'push');
            app.CreateCategoryButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCategoryButtonPushed, true);
            app.CreateCategoryButton.Text = 'Create Category';
            app.DataTypeButtonGroup = uibuttongroup(app.CategoryDetailsPanel);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.MainButton = uiradiobutton(app.DataTypeButtonGroup);
            app.MainButton.Text = 'Main';
            app.MainButton.Value = true;
            app.EndmemberButton = uiradiobutton(app.DataTypeButtonGroup);
            app.EndmemberButton.Text = 'Endmember';
            app.LoadPreferencesButton = uibutton(app.PlotOptionsTab, 'push');
            app.LoadPreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreferencesButtonPushed, true);
            app.LoadPreferencesButton.Tooltip = {'Loads a previously saved .mat file using the "save categories" button'};
            app.LoadPreferencesButton.Text = 'Load Preferences';
            app.SavePreferencesButton = uibutton(app.PlotOptionsTab, 'push');
            app.SavePreferencesButton.ButtonPushedFcn = createCallbackFcn(app, @SavePreferencesButtonPushed, true);
            app.SavePreferencesButton.Tooltip = {'Saves the selected locations, endmembers, date ranges, and their plot options.'};
            app.SavePreferencesButton.Text = 'Save Preferences';
            app.UITable5_2 = uitable(app.PlotOptionsTab);
            app.UITable5_2.ColumnName = {'Category Name'; 'Location'; 'Start Date'; 'End Date'; 'Symbol'; 'Color'};
            app.UITable5_2.RowName = {};
            app.UITable5_2.CellSelectionCallback = createCallbackFcn(app, @UITable5_2CellSelection, true);
            app.MixtureDataLabel = uilabel(app.PlotOptionsTab);
            app.MixtureDataLabel.Text = 'Mixture Data';
            app.EndmemberDataLabel = uilabel(app.PlotOptionsTab);
            app.EndmemberDataLabel.Text = 'Endmember Data';
            app.CorrelationMatrixTab = uitab(app.TabGroup);
            app.CorrelationMatrixTab.Title = 'Correlation Matrix';
            app.TabGroup3 = uitabgroup(app.CorrelationMatrixTab);
            app.ScatterPlotCorrelationsTab = uitab(app.TabGroup3);
            app.ScatterPlotCorrelationsTab.Title = 'Scatter Plot Correlations';
            app.UIAxes4 = uiaxes(app.ScatterPlotCorrelationsTab);
            title(app.UIAxes4, 'Scatter Correlations')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.CorrelationsbyRTab = uitab(app.TabGroup3);
            app.CorrelationsbyRTab.Title = 'Correlations by R';
            app.Panel_2 = uipanel(app.CorrelationsbyRTab);
            app.ElementSelectorPanel = uipanel(app.CorrelationMatrixTab);
            app.ElementSelectorPanel.Title = 'Element Selector';
            app.UITable11 = uitable(app.ElementSelectorPanel);
            app.UITable11.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable11.RowName = {};
            app.UITable11.ColumnEditable = true;
            app.UITable11.CellEditCallback = createCallbackFcn(app, @UITable11CellEdit, true);
            app.CreateCorrelationMatrixButton = uibutton(app.ElementSelectorPanel, 'push');
            app.CreateCorrelationMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCorrelationMatrixButtonPushed, true);
            app.CreateCorrelationMatrixButton.Text = 'Create Correlation Matrix';
            app.PlotStatusLampLabel = uilabel(app.ElementSelectorPanel);
            app.PlotStatusLampLabel.Text = 'Plot Status';
            app.PlotStatusLamp = uilamp(app.ElementSelectorPanel);
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.TabGroup5 = uitabgroup(app.PCATab);
            app.DPCATab = uitab(app.TabGroup5);
            app.DPCATab.Title = '2D PCA';
            app.UIAxes7 = uiaxes(app.DPCATab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.DPCATab_2 = uitab(app.TabGroup5);
            app.DPCATab_2.Title = '3D PCA';
            app.UIAxes8 = uiaxes(app.DPCATab_2);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.ResidualsTab = uitab(app.TabGroup5);
            app.ResidualsTab.Title = 'Residuals';
            app.UIAxes2 = uiaxes(app.ResidualsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PlottingOptionsButtonGroup = uibuttongroup(app.ResidualsTab);
            app.PlottingOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResidualPlotRefresh, true);
            app.PlottingOptionsButtonGroup.Title = 'Plotting Options';
            app.ResidualsButton = uiradiobutton(app.PlottingOptionsButtonGroup);
            app.ResidualsButton.Text = 'Residuals';
            app.ResidualsButton.Value = true;
            app.ScreePlotButton = uiradiobutton(app.PlottingOptionsButtonGroup);
            app.ScreePlotButton.Text = 'Scree Plot';
            app.CalculateResidualsPanel = uipanel(app.ResidualsTab);
            app.CalculateResidualsPanel.Title = 'Calculate Residuals';
            app.ofPCsSpinnerLabel = uilabel(app.CalculateResidualsPanel);
            app.ofPCsSpinnerLabel.Text = '# of PC''s';
            app.ofPCsSpinner = uispinner(app.CalculateResidualsPanel);
            app.ofPCsSpinner.Limits = [0 Inf];
            app.CalculateButton = uibutton(app.CalculateResidualsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @ResidualPlotRefresh, true);
            app.CalculateButton.Text = 'Calculate';
            app.PlotResidualsforDropDownLabel = uilabel(app.CalculateResidualsPanel);
            app.PlotResidualsforDropDownLabel.Text = 'Plot Residuals for';
            app.ResidualsDropDown = uidropdown(app.CalculateResidualsPanel);
            app.ResidualsDropDown.Items = {'d18O', 'Cl', 'Si', 'Na'};
            app.ResidualsDropDown.Value = 'd18O';
            app.CoefficientsTab = uitab(app.TabGroup5);
            app.CoefficientsTab.Title = 'Coefficients';
            app.UITable3 = uitable(app.CoefficientsTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.PCScoresTab = uitab(app.TabGroup5);
            app.PCScoresTab.Title = 'PC Scores';
            app.UITable4 = uitable(app.PCScoresTab);
            app.UITable4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.TracerSelectionPanel = uipanel(app.PCATab);
            app.TracerSelectionPanel.Title = 'Tracer Selection';
            app.UITable9 = uitable(app.TracerSelectionPanel);
            app.UITable9.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable9.RowName = {};
            app.UITable9.CellEditCallback = createCallbackFcn(app, @UITable11CellEdit, true);
            app.PercentExplainedPanel = uipanel(app.PCATab);
            app.PercentExplainedPanel.Title = 'Percent Explained';
            app.UITable10 = uitable(app.PercentExplainedPanel);
            app.UITable10.ColumnName = {'Percent Explained'};
            app.UITable10.RowName = {};
            app.PCAPreferencesPanel = uipanel(app.PCATab);
            app.PCAPreferencesPanel.Title = 'PCA Preferences';
            app.XaxisLabel = uilabel(app.PCAPreferencesPanel);
            app.XaxisLabel.Text = 'X-axis';
            app.XAxisDropDown = uidropdown(app.PCAPreferencesPanel);
            app.XAxisDropDown.Items = {'PC1', 'PC2', 'PC3'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @RefreshPCA, true);
            app.XAxisDropDown.Value = 'PC1';
            app.YaxesLabel = uilabel(app.PCAPreferencesPanel);
            app.YaxesLabel.Text = 'Y-axis';
            app.YAxisDropDown = uidropdown(app.PCAPreferencesPanel);
            app.YAxisDropDown.Items = {'PC1', 'PC2', 'PC3'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @RefreshPCA, true);
            app.YAxisDropDown.Value = 'PC2';
            app.ZaxisDropDownLabel = uilabel(app.PCAPreferencesPanel);
            app.ZaxisDropDownLabel.Text = 'Z-axis';
            app.ZAxisDropDown_2 = uidropdown(app.PCAPreferencesPanel);
            app.ZAxisDropDown_2.Items = {'PC1', 'PC2', 'PC3'};
            app.ZAxisDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RefreshPCA, true);
            app.ZAxisDropDown_2.Value = 'PC3';
            app.ClearPlotButton_4 = uibutton(app.PCAPreferencesPanel, 'push');
            app.ClearPlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton_4.Text = 'Clear Plot';
            app.SavePCDataButton = uibutton(app.PCATab, 'push');
            app.SavePCDataButton.ButtonPushedFcn = createCallbackFcn(app, @SavePCDataButtonPushed, true);
            app.SavePCDataButton.Text = 'Save PC Data';
            app.EndmemberButtonGroup = uibuttongroup(app.PCATab);
            app.EndmemberButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EndmemberButtonGroupSelectionChanged, true);
            app.EndmemberButtonGroup.Title = 'Endmember View';
            app.EndmembersallButton = uiradiobutton(app.EndmemberButtonGroup);
            app.EndmembersallButton.Text = 'Endmembers (all)';
            app.EndmembersallButton.Value = true;
            app.AveragedEndmembersButton = uiradiobutton(app.EndmemberButtonGroup);
            app.AveragedEndmembersButton.Text = 'Averaged Endmembers';
            app.MixingCalculationTab = uitab(app.TabGroup);
            app.MixingCalculationTab.Title = 'Mixing Calculation';
            app.TabGroup7 = uitabgroup(app.MixingCalculationTab);
            app.MixingSummaryTab = uitab(app.TabGroup7);
            app.MixingSummaryTab.Title = 'Mixing Summary';
            app.UITable6 = uitable(app.MixingSummaryTab);
            app.UITable6.ColumnName = {'Location'; 'Date'; 'Endmember 1'; 'Endmember 2'; 'Endmember 3'; 'Endmember 4'};
            app.UITable6.RowName = {};
            app.MixingAnalysisSummaryPanel = uipanel(app.MixingSummaryTab);
            app.MixingAnalysisSummaryPanel.Title = 'Mixing Analysis Summary';
            app.LocationsinanalysisLabel = uilabel(app.MixingAnalysisSummaryPanel);
            app.LocationsinanalysisLabel.Text = 'Location(s) in analysis:';
            app.LocListBox = uilistbox(app.MixingAnalysisSummaryPanel);
            app.SelectedEndmembersLabel = uilabel(app.MixingAnalysisSummaryPanel);
            app.SelectedEndmembersLabel.Text = 'Endmembers in analysis:';
            app.SelEMListBox = uilistbox(app.MixingAnalysisSummaryPanel);
            app.UITable12 = uitable(app.MixingAnalysisSummaryPanel);
            app.UITable12.ColumnName = {'Selected Tracers'};
            app.UITable12.RowName = {};
            app.CalculateFractionalContributionsButton = uibutton(app.MixingAnalysisSummaryPanel, 'push');
            app.CalculateFractionalContributionsButton.ButtonPushedFcn = createCallbackFcn(app, @PerContributionsButtonPushed, true);
            app.CalculateFractionalContributionsButton.Text = 'Calculate Fractional Contributions';
            app.CalculationStatusLampLabel = uilabel(app.MixingAnalysisSummaryPanel);
            app.CalculationStatusLampLabel.Text = 'Calculation Status';
            app.CalculationStatusLamp = uilamp(app.MixingAnalysisSummaryPanel);
            app.SaveMixingResultsButton = uibutton(app.MixingAnalysisSummaryPanel, 'push');
            app.SaveMixingResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMixingResultsButtonPushed, true);
            app.SaveMixingResultsButton.Text = 'Save Mixing Results';
            app.MixingCalculationPreferencesButtonGroup = uibuttongroup(app.MixingAnalysisSummaryPanel);
            app.MixingCalculationPreferencesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MixingCalculationPreferencesButtonGroupSelectionChanged, true);
            app.MixingCalculationPreferencesButtonGroup.Title = 'Mixing Calculation Preferences';
            app.TracerSpaceButton = uiradiobutton(app.MixingCalculationPreferencesButtonGroup);
            app.TracerSpaceButton.Text = 'Tracer Space';
            app.TracerSpaceButton.Value = true;
            app.USpacenotrecommendedButton = uiradiobutton(app.MixingCalculationPreferencesButtonGroup);
            app.USpacenotrecommendedButton.Text = 'U-Space (not recommended)';
            app.USpaceCalculationPreferencesButtonGroup = uibuttongroup(app.MixingAnalysisSummaryPanel);
            app.USpaceCalculationPreferencesButtonGroup.Title = 'U-Space Calculation Preferences';
            app.PCButton = uiradiobutton(app.USpaceCalculationPreferencesButtonGroup);
            app.PCButton.Text = '2 PC';
            app.PCButton.Value = true;
            app.PCButton_2 = uiradiobutton(app.USpaceCalculationPreferencesButtonGroup);
            app.PCButton_2.Text = '3 PC';
            app.MixingPlotViewerTab = uitab(app.TabGroup7);
            app.MixingPlotViewerTab.Title = 'Mixing Plot Viewer';
            app.UIAxes5 = uiaxes(app.MixingPlotViewerTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Panel_3 = uipanel(app.MixingPlotViewerTab);
            app.LocationDropDownLabel = uilabel(app.Panel_3);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.Panel_3);
            app.LocationDropDown.Items = {'Soapstone', 'Woodland', 'Hailstone', 'Other'};
            app.LocationDropDown.Value = 'Soapstone';
            app.PlotFractionalContributionOverTimeButton = uibutton(app.Panel_3, 'push');
            app.PlotFractionalContributionOverTimeButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPerContributionsPlot, true);
            app.PlotFractionalContributionOverTimeButton.Text = {'Plot Fractional Contribution Over Time'; ''};
            app.PredictedvObservedTab = uitab(app.TabGroup);
            app.PredictedvObservedTab.Title = 'Predicted v. Observed';
            app.UIAxes9 = uiaxes(app.PredictedvObservedTab);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.PredictedCalculationSettingsPanel = uipanel(app.PredictedvObservedTab);
            app.PredictedCalculationSettingsPanel.Title = 'Predicted Calculation Settings';
            app.TracerDropDownLabel = uilabel(app.PredictedCalculationSettingsPanel);
            app.TracerDropDownLabel.Text = 'Tracer';
            app.TracerDropDown = uidropdown(app.PredictedCalculationSettingsPanel);
            app.PlotPredictionButton = uibutton(app.PredictedCalculationSettingsPanel, 'push');
            app.PlotPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPredictionButtonPushed, true);
            app.PlotPredictionButton.Tooltip = {'Make sure to ''Clear Plot'' between prediction plots.'};
            app.PlotPredictionButton.Text = 'Plot Prediction';
            app.ClearPlotButton_5 = uibutton(app.PredictedCalculationSettingsPanel, 'push');
            app.ClearPlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton_5.Text = 'Clear Plot';
            app.UITable16 = uitable(app.PredictedCalculationSettingsPanel);
            app.UITable16.ColumnName = {'R^2'; 'slope-1'; 'y-intercept'};
            app.UITable16.RowName = {};
            app.ErrorCalculationTab = uitab(app.TabGroup);
            app.ErrorCalculationTab.Title = 'Error Calculation';
            app.TabGroup8 = uitabgroup(app.ErrorCalculationTab);
            app.ErrorPlotTab = uitab(app.TabGroup8);
            app.ErrorPlotTab.Title = 'Error Plot';
            app.UIAxes6 = uiaxes(app.ErrorPlotTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.JackknifedMeanValuesTab = uitab(app.TabGroup8);
            app.JackknifedMeanValuesTab.Title = 'Jackknifed Mean Values';
            app.UITable7 = uitable(app.JackknifedMeanValuesTab);
            app.UITable7.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable7.RowName = {};
            app.JackknifedCalculatedFractionalContributionsLabel = uilabel(app.JackknifedMeanValuesTab);
            app.JackknifedCalculatedFractionalContributionsLabel.Text = 'Jackknifed Calculated Fractional Contributions';
            app.JackknifedStandardDeviationValuesTab = uitab(app.TabGroup8);
            app.JackknifedStandardDeviationValuesTab.Title = 'Jackknifed Standard Deviation Values';
            app.UITable8 = uitable(app.JackknifedStandardDeviationValuesTab);
            app.UITable8.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable8.RowName = {};
            app.JackknifedCalculatedStandardDeviationLabel = uilabel(app.JackknifedStandardDeviationValuesTab);
            app.JackknifedCalculatedStandardDeviationLabel.Text = 'Jackknifed Calculated Standard Deviation';
            app.ErrorPlotterPanel = uipanel(app.ErrorCalculationTab);
            app.ErrorPlotterPanel.Title = 'Error Plotter';
            app.LocationDropDown_2Label = uilabel(app.ErrorPlotterPanel);
            app.LocationDropDown_2Label.Text = 'Location';
            app.LocationDropDown_2 = uidropdown(app.ErrorPlotterPanel);
            app.PlotErrorButton = uibutton(app.ErrorPlotterPanel, 'push');
            app.PlotErrorButton.ButtonPushedFcn = createCallbackFcn(app, @PlotErrorButtonPushed, true);
            app.PlotErrorButton.Text = 'Plot Error';
            app.ClearPlotButton_6 = uibutton(app.ErrorPlotterPanel, 'push');
            app.ClearPlotButton_6.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton_6.Text = 'Clear Plot';
            app.ErrorCalculationPanel = uipanel(app.ErrorCalculationTab);
            app.ErrorCalculationPanel.Title = 'Error Calculation';
            app.CalculatePercentErrorButton = uibutton(app.ErrorCalculationPanel, 'push');
            app.CalculatePercentErrorButton.ButtonPushedFcn = createCallbackFcn(app, @JackknifingCalculation, true);
            app.CalculatePercentErrorButton.Text = 'Calculate Percent Error';
            app.ErrorCalculationStatusLampLabel = uilabel(app.ErrorCalculationPanel);
            app.ErrorCalculationStatusLampLabel.Text = 'Error Calculation Status';
            app.ErrorCalculationStatusLamp = uilamp(app.ErrorCalculationPanel);
            app.TimeElapsedminTextAreaLabel = uilabel(app.ErrorCalculationPanel);
            app.TimeElapsedminTextAreaLabel.Text = 'Time Elapsed (min):';
            app.TimeElapsedminTextArea = uitextarea(app.ErrorCalculationPanel);
            app.TextArea = uitextarea(app.ErrorCalculationTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Please note that this step can take a considerable amount of time depending on the user''s sample set of endmembers. Using the Provo River example dataset, it took us 6 hours using a computer with 8 cores.'};
            app.SaveJackknifeDataButton = uibutton(app.ErrorCalculationTab, 'push');
            app.SaveJackknifeDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveJackknifeDataButtonPushed, true);
            app.SaveJackknifeDataButton.Text = 'Save Jackknife Data';
            app.PlotViewerTab = uitab(app.TabGroup);
            app.PlotViewerTab.Title = 'Plot Viewer';
            app.TabGroup6 = uitabgroup(app.PlotViewerTab);
            app.TimeSeriesTab = uitab(app.TabGroup6);
            app.TimeSeriesTab.Title = 'Time Series';
            app.UIAxes_2 = uiaxes(app.TimeSeriesTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.TimeSeriesPreferencesPanel = uipanel(app.TimeSeriesTab);
            app.TimeSeriesPreferencesPanel.Title = 'Time Series Preferences';
            app.XAxisDropDown_7Label = uilabel(app.TimeSeriesPreferencesPanel);
            app.XAxisDropDown_7Label.Text = 'X-Axis';
            app.XAxisDropDown_2 = uidropdown(app.TimeSeriesPreferencesPanel);
            app.XAxisDropDown_2.Items = {'SampleDate', 'Date', 'Discharge', 'Location'};
            app.XAxisDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TimeSeriesAxes, true);
            app.XAxisDropDown_2.Value = 'SampleDate';
            app.YAxisDropDown_7Label = uilabel(app.TimeSeriesPreferencesPanel);
            app.YAxisDropDown_7Label.Text = 'Y-Axis';
            app.YAxisDropDown_2 = uidropdown(app.TimeSeriesPreferencesPanel);
            app.YAxisDropDown_2.Items = {'Li', 'Be', 'B', 'Na'};
            app.YAxisDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TimeSeriesAxes, true);
            app.YAxisDropDown_2.Value = 'Li';
            app.ClearPlotButton_2 = uibutton(app.TimeSeriesPreferencesPanel, 'push');
            app.ClearPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton_2.Text = 'Clear Plot';
            app.AxesTitlePreferencesPanel = uipanel(app.TimeSeriesTab);
            app.AxesTitlePreferencesPanel.Title = 'Axes Title Preferences';
            app.UpdateAxesLabelsButton = uibutton(app.AxesTitlePreferencesPanel, 'push');
            app.UpdateAxesLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAxesLabelsButtonPushed, true);
            app.UpdateAxesLabelsButton.Text = 'Update Axes Labels';
            app.XAxisLabelEditFieldLabel = uilabel(app.AxesTitlePreferencesPanel);
            app.XAxisLabelEditFieldLabel.Text = 'X-Axis Label';
            app.XAxisLabelEditField = uieditfield(app.AxesTitlePreferencesPanel, 'text');
            app.XAxisLabelEditField.Value = 'Date';
            app.YAxisLabelEditFieldLabel = uilabel(app.AxesTitlePreferencesPanel);
            app.YAxisLabelEditFieldLabel.Text = 'Y-Axis Label';
            app.YAxisLabelEditField = uieditfield(app.AxesTitlePreferencesPanel, 'text');
            app.YAxisLabelEditField.Value = 'Li (mg/kg)';
            app.TitleEditFieldLabel = uilabel(app.AxesTitlePreferencesPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.AxesTitlePreferencesPanel, 'text');
            app.TitleEditField.Value = 'Li (mg/kg)';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Panel_4 = uipanel(app.HelpTab);
            app.Panel_5 = uipanel(app.Panel_4);
            app.TextArea_4 = uitextarea(app.Panel_5);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Instructional YouTube app walkthrough: '; 'HydroShare download for all materials:'; ''; ''; ''; 'All necessary files for running EMMALAB either through MATLAB or through standalone Windows and Mac versions are included. This includes MATLAB functions and .jpg images used inside the app, the beginner spreadsheet template for uploading the data to the app, and the video script for the YouTube tutorial.'; ''; ''; ''; 'All instructions for using the app are shown through the YouTube video, starting from organizing data onto the spreadsheet template to exporting mixing results. '; ''; ''};
            app.Hyperlink = uihyperlink(app.Panel_5);
            app.Hyperlink.URL = 'https://www.youtube.com/watch?v=_oOI6bqL5a4';
            app.Hyperlink.Text = 'Tutorial';
            app.HelpandSupportLabel = uilabel(app.Panel_5);
            app.HelpandSupportLabel.Text = 'Help and Support';
            app.IncludedMaterialsLabel = uilabel(app.Panel_5);
            app.IncludedMaterialsLabel.Text = 'Included Materials';
            app.WhatisincludedintheHydroSharedownloadLabel = uilabel(app.Panel_5);
            app.WhatisincludedintheHydroSharedownloadLabel.Text = '''What is included in the HydroShare download?''';
            app.Hyperlink_2 = uihyperlink(app.Panel_5);
            app.Hyperlink_2.URL = 'http://www.hydroshare.org/resource/58b0f961c81a41939415526c8ad78d33';
            app.Hyperlink_2.Text = 'HydroShare';
            app.ContactUsLabel = uilabel(app.Panel_5);
            app.ContactUsLabel.Text = 'Contact Us';
            app.HowdoIusetheappLabel = uilabel(app.Panel_5);
            app.HowdoIusetheappLabel.Text = '''How do I use the app?''';
            app.TextArea_5 = uitextarea(app.Panel_5);
            app.TextArea_5.Value = {'For any questions regarding the use of the app, please reach out at emmalabbyu@gmail.com'};
            app.TextArea_6 = uitextarea(app.Panel_5);
            app.TextArea_6.Value = {'App Citation:'; 'Thompson, A. N., Bickmore, B. R., Evans, E. J., Carling, G. T. (2023). EMMALAB v. 1.0, [MATLAB app], HydroShare, http://www.hydroshare.org/resource/58b0f961c81a41939415526c8ad78d33'; ''; 'Thesis Citation:'; 'Thompson, A. N. (2023). "Improved endmember mixing analysis (EMMA): Application to a nested catchment, Provo River, northern Utah," [Masters Thesis, Brigham Young University].'; ''; 'Journal Article Citation:'; 'Thompson, A. N., Bickmore, B. R., Carling, G. T., Evans, E. J., Fernandez, D. P., Nelson, S. T., Rey, K. A., LeMonte, J. J., Caskey, K. L. (submitted). "Improved endmember mixing analysis (EMMA): Application to a nested catchment, Provo River, northern Utah," Water Resources Research.'};
            app.CitationGuideLabel = uilabel(app.Panel_5);
            app.CitationGuideLabel.Text = 'Citation Guide';
            app.Image_7 = uiimage(app.Panel_5);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'EMMA1.png');
            app.EMMALABv10Label_2 = uilabel(app.UIFigure);
            app.EMMALABv10Label_2.Text = 'EMMALAB v. 1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMMALAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end